classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OCRLabel                       matlab.ui.control.Label
        OpticalCharacterRecognitionLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ArchitectureButtonGroup        matlab.ui.container.ButtonGroup
        AssociativememoryClassifierButton  matlab.ui.control.RadioButton
        ClassifierwithonelayerButton   matlab.ui.control.RadioButton
        ClassifierwithtwolayersButton  matlab.ui.control.RadioButton
        BinaryperceptronClassifierButton  matlab.ui.control.RadioButton
        ClassifierwithsoftmaxlayerButton  matlab.ui.control.RadioButton
        ActivationfunctionButtonGroup  matlab.ui.container.ButtonGroup
        HardlimButton                  matlab.ui.control.RadioButton
        PurelinButton                  matlab.ui.control.RadioButton
        LogsigButton                   matlab.ui.control.RadioButton
        PurelinPurelinButton           matlab.ui.control.RadioButton
        PurelinLogsigButton            matlab.ui.control.RadioButton
        LogsigPurelinButton            matlab.ui.control.RadioButton
        LogsigLogsigButton             matlab.ui.control.RadioButton
        HelpButton_softmax             matlab.ui.control.Button
        TrainnetworkandtestButton      matlab.ui.control.Button
        TesttrainednetworkButton       matlab.ui.control.Button
        HelpButton_Train               matlab.ui.control.Button
        HelpButton_Test                matlab.ui.control.Button
        Label_blue                     matlab.ui.control.Label
        ComputaoNeuronaleSistemasDifusos202021Label_2  matlab.ui.control.Label
        PL4Grupo3AndrBernardesJoanaBaioLabel_2  matlab.ui.control.Label
        INFOButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        nn_architecture = 'AMC'
        activation_function = 'hardlim'
        choice = 'train';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ArchitectureButtonGroup
        function ArchitectureButtonGroupSelectionChanged(app, event)
            selectedButton = app.ArchitectureButtonGroup.SelectedObject;
            
            handleArray1 = [app.PurelinButton, app.LogsigButton]; 
            
            %Conjunto de opções para a arquitetura de classifier com 2 layers
            handleArray2 = [app.PurelinPurelinButton, app.PurelinLogsigButton, app.LogsigPurelinButton, app.LogsigLogsigButton];
      
            if selectedButton == app.AssociativememoryClassifierButton
                app.nn_architecture = 'AMC';
                
                set(app.HardlimButton, 'Visible', 'on');
                set(handleArray1, 'Visible', 'on');
                set(handleArray2, 'Visible', 'off');
                set(app.HelpButton_softmax, 'Visible', 'off');
           
            elseif selectedButton == app.BinaryperceptronClassifierButton
                app.nn_architecture = 'BPC';
                
                set(app.HardlimButton, 'Visible', 'on');
                set(handleArray1, 'Visible', 'on');
                set(handleArray2, 'Visible', 'off');
                set(app.HelpButton_softmax, 'Visible', 'off');
                
            elseif selectedButton == app.ClassifierwithonelayerButton
                app.nn_architecture = 'C1';
                
                set(app.HardlimButton, 'Visible', 'on');
                set(handleArray1, 'Visible', 'on');
                set(handleArray2, 'Visible', 'off');
                set(app.HelpButton_softmax, 'Visible', 'off');
                
            elseif selectedButton == app.ClassifierwithsoftmaxlayerButton
                app.nn_architecture = 'CS';
                
                set(app.HardlimButton, 'Visible', 'off');
                set(handleArray1, 'Visible', 'on');
                set(handleArray2, 'Visible', 'off');
                set(app.HelpButton_softmax, 'Visible', 'on');
                
            elseif selectedButton == app.ClassifierwithtwolayersButton
                app.nn_architecture = 'C2';
                
                set(app.HardlimButton, 'Visible', 'off');
                set(handleArray1, 'Visible', 'off');
                set(handleArray2, 'Visible', 'on');
                set(app.HelpButton_softmax, 'Visible', 'off');
                
                % Para ficarem disponíveis as opções de configuração para a
                % arquitetura do classifier com 2 layers (sem a função de
                % ativação hardlim e com a opção de escolher a função de
                % ativação de cada uma das 2 layers)
                
            end  
        end

        % Selection changed function: ActivationfunctionButtonGroup
        function ActivationfunctionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ActivationfunctionButtonGroup.SelectedObject;
            
            if selectedButton == app.HardlimButton
                app.activation_function = 'hardlim';
                
            elseif selectedButton == app.PurelinButton
                app.activation_function = 'purelin';
                          
            elseif selectedButton == app.LogsigButton
                app.activation_function = 'logsig';
                
            elseif selectedButton == app.PurelinPurelinButton
                app.activation_function = ["purelin", "purelin"];
                
            elseif selectedButton == app.PurelinLogsigButton
                app.activation_function = ["purelin", "logsig"];
            
            elseif selectedButton == app.LogsigPurelinButton
                app.activation_function = ["logsig", "purelin"];
            
            elseif selectedButton == app.LogsigLogsigButton
                app.activation_function = ["logsig", "logsig"];
                
            end
            
        end

        % Button pushed function: TrainnetworkandtestButton
        function TrainnetworkandtestButtonPushed(app, event)
            main(app.nn_architecture, app.activation_function, 'train');
        end

        % Button pushed function: TesttrainednetworkButton
        function TesttrainednetworkButtonPushed(app, event)
            main(app.nn_architecture, app.activation_function, 'test');
        end

        % Button pushed function: HelpButton_Train
        function HelpButton_TrainPushed(app, event)
            msgbox(['Train a neural network with a chosen activation function and neural network architecture,' ...
                ' and test it in two testing datasets'],'Help', 'help');
        end

        % Button pushed function: HelpButton_Test
        function HelpButton_TestPushed(app, event)
             msgbox(['Test a previously trained network with a chosen activation function and ' ...
                 ['neural network architecture by drawing several numbers on the grid. ' ...
                 'The results predicted by the trained neural network will be shown.']
               ],'Help', 'help');
        end

        % Button pushed function: HelpButton_softmax
        function HelpButton_softmaxPushed(app, event)
            msgbox(['The softmax function is a form of logistic regression that normalizes ' ...
                'an input value into a vector of values that follows a probability distribution whose total sums up to 1.' ...
                ' The output values are between the range [0,1].'],'Help', 'help');
        end

        % Button pushed function: INFOButton
        function INFOButtonPushed(app, event)
            msgbox(['The best accuracy classifier is BP + C with the purelin function. ' ...
                'We advise the use of the full screen when drawing the numbers on the grid.'],'Help', 'help');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OCRLabel = uilabel(app.UIFigure);
            app.OCRLabel.Text = 'OCR';
            app.OpticalCharacterRecognitionLabel = uilabel(app.UIFigure);
            app.OpticalCharacterRecognitionLabel.Text = 'Optical Character Recognition';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '__________________';
            app.ArchitectureButtonGroup = uibuttongroup(app.UIFigure);
            app.ArchitectureButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ArchitectureButtonGroupSelectionChanged, true);
            app.ArchitectureButtonGroup.Title = 'Architecture';
            app.AssociativememoryClassifierButton = uiradiobutton(app.ArchitectureButtonGroup);
            app.AssociativememoryClassifierButton.Text = 'Associative memory + Classifier';
            app.AssociativememoryClassifierButton.Value = true;
            app.ClassifierwithonelayerButton = uiradiobutton(app.ArchitectureButtonGroup);
            app.ClassifierwithonelayerButton.Text = 'Classifier with one layer';
            app.ClassifierwithtwolayersButton = uiradiobutton(app.ArchitectureButtonGroup);
            app.ClassifierwithtwolayersButton.Text = 'Classifier with two layers';
            app.BinaryperceptronClassifierButton = uiradiobutton(app.ArchitectureButtonGroup);
            app.BinaryperceptronClassifierButton.Text = 'Binary perceptron + Classifier';
            app.ClassifierwithsoftmaxlayerButton = uiradiobutton(app.ArchitectureButtonGroup);
            app.ClassifierwithsoftmaxlayerButton.Text = 'Classifier with softmax layer';
            app.ActivationfunctionButtonGroup = uibuttongroup(app.UIFigure);
            app.ActivationfunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ActivationfunctionButtonGroupSelectionChanged, true);
            app.ActivationfunctionButtonGroup.Title = 'Activation function';
            app.HardlimButton = uiradiobutton(app.ActivationfunctionButtonGroup);
            app.HardlimButton.Text = 'Hardlim';
            app.HardlimButton.Value = true;
            app.PurelinButton = uiradiobutton(app.ActivationfunctionButtonGroup);
            app.PurelinButton.Text = 'Purelin';
            app.LogsigButton = uiradiobutton(app.ActivationfunctionButtonGroup);
            app.LogsigButton.Text = 'Logsig';
            app.PurelinPurelinButton = uiradiobutton(app.ActivationfunctionButtonGroup);
            app.PurelinPurelinButton.Visible = 'off';
            app.PurelinPurelinButton.Text = 'Purelin + Purelin';
            app.PurelinLogsigButton = uiradiobutton(app.ActivationfunctionButtonGroup);
            app.PurelinLogsigButton.Visible = 'off';
            app.PurelinLogsigButton.Text = 'Purelin + Logsig';
            app.LogsigPurelinButton = uiradiobutton(app.ActivationfunctionButtonGroup);
            app.LogsigPurelinButton.Visible = 'off';
            app.LogsigPurelinButton.Text = 'Logsig + Purelin';
            app.LogsigLogsigButton = uiradiobutton(app.ActivationfunctionButtonGroup);
            app.LogsigLogsigButton.Visible = 'off';
            app.LogsigLogsigButton.Text = 'Logsig + Logsig';
            app.HelpButton_softmax = uibutton(app.ActivationfunctionButtonGroup, 'push');
            app.HelpButton_softmax.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_softmaxPushed, true);
            app.HelpButton_softmax.Visible = 'off';
            app.HelpButton_softmax.Text = 'Help';
            app.TrainnetworkandtestButton = uibutton(app.UIFigure, 'push');
            app.TrainnetworkandtestButton.ButtonPushedFcn = createCallbackFcn(app, @TrainnetworkandtestButtonPushed, true);
            app.TrainnetworkandtestButton.Text = 'Train network and test';
            app.TesttrainednetworkButton = uibutton(app.UIFigure, 'push');
            app.TesttrainednetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TesttrainednetworkButtonPushed, true);
            app.TesttrainednetworkButton.Text = 'Test trained network';
            app.HelpButton_Train = uibutton(app.UIFigure, 'push');
            app.HelpButton_Train.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_TrainPushed, true);
            app.HelpButton_Train.Text = 'Help';
            app.HelpButton_Test = uibutton(app.UIFigure, 'push');
            app.HelpButton_Test.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_TestPushed, true);
            app.HelpButton_Test.Text = 'Help';
            app.Label_blue = uilabel(app.UIFigure);
            app.Label_blue.Text = '';
            app.ComputaoNeuronaleSistemasDifusos202021Label_2 = uilabel(app.UIFigure);
            app.ComputaoNeuronaleSistemasDifusos202021Label_2.Text = 'Computação Neuronal e Sistemas Difusos - 2020/21';
            app.PL4Grupo3AndrBernardesJoanaBaioLabel_2 = uilabel(app.UIFigure);
            app.PL4Grupo3AndrBernardesJoanaBaioLabel_2.Text = 'PL4 - Grupo 3 - André Bernardes & Joana Baião';
            app.INFOButton = uibutton(app.UIFigure, 'push');
            app.INFOButton.ButtonPushedFcn = createCallbackFcn(app, @INFOButtonPushed, true);
            app.INFOButton.Text = '+ INFO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end